classdef Lloyd_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel        matlab.ui.control.Label
        SideLengthEditField             matlab.ui.control.NumericEditField
        MaximumVelocityEditFieldLabel   matlab.ui.control.Label
        MaximumVelocityEditField        matlab.ui.control.NumericEditField
        DensityTypeDropDownLabel        matlab.ui.control.Label
        DensityTypeDropDown             matlab.ui.control.DropDown
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        FinalIterationEditFieldLabel    matlab.ui.control.Label
        FinalIterationEditField         matlab.ui.control.NumericEditField
        PlayButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RandomizePositionsButton        matlab.ui.control.Button
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
        OrderFrequencyEditFieldLabel    matlab.ui.control.Label
        OrderFrequencyEditField         matlab.ui.control.NumericEditField
        RandomDelayCheckBox             matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
        OrderPickupTimeEditFieldLabel   matlab.ui.control.Label
        OrderPickupTimeEditField        matlab.ui.control.NumericEditField
        OrderQueueLabel                 matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
    end


    methods (Access = private)
        
   %%%%%%%%%%%%%%%%%%%%%%%%% THESE ARE LOCAL PLOTTING FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%
    
    function [] = circle_Maker(app, x_centre,y_centre,r_o)
    % Draws a circle of specified radius around point (x_centre,y_centre)
    
    theta = linspace(0,2*pi,100);
    x = r_o.*cos(theta) + x_centre;
    y = r_o.*sin(theta) + y_centre;
    
    plot(app.UIAxes,x,y,'magenta')
    end
    
    function [] = Plot_Assigned_Points(app, Agent_Points, Number_of_Agents,...
        colours_energy,Agent_Positions,sides)
    
        for i = 1:Number_of_Agents
            hold(app.UIAxes3, 'on')
            scatter(app.UIAxes3, Agent_Points{1,i}(:,1),Agent_Points{1,i}(:,2),...
                'MarkerEdgeColor',colours_energy(i,:));
            hold(app.UIAxes3, 'on')
            scatter(app.UIAxes3, Agent_Positions(i,1),Agent_Positions(i,2),50,...
                colours_energy(i,:),'filled','diamond')
            label_agent_index = num2str(i);
            t.FontSize = 15;
            text(app.UIAxes3, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
                        label_agent_index);
            app.UIAxes3.XLim = [0 app.SideLengthEditField.Value];
            app.UIAxes3.YLim = [0 app.SideLengthEditField.Value];
        end
    end 
    
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END OF LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    end 


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%Generates random data upon startup (3rd column is just the number of the agent)
            
%Default config
app.NumberofAgentsEditField.Value = 9;
app.SideLengthEditField.Value = 20;
app.MaximumVelocityEditField.Value = 4;
app.DensityTypeDropDown.Value = 'Ones';
app.DelayEditField.Value = 5;
app.FinalIterationEditField.Value= 200;
app.DensityMatrixNameEditField.Value = '';
app.OrderFrequencyEditField.Value = 10;
app.RandomDelayCheckBox.Value = 0;
app.OrderPickupTimeEditField.Value = 3;

n = app.NumberofAgentsEditField.Value;
L = app.SideLengthEditField.Value;

Agent_Positions = zeros(n,3);

for i = 1:n
    Agent_Positions(i,1) = randi(L-1);
    Agent_Positions(i,2) = randi(L-1);
    Agent_Positions(i,3) = i;
end

app.UITable.Data = Agent_Positions;
        end

        % Button pushed function: RandomizePositionsButton
        function RandomizePositionsButtonPushed(app, event)
%Randomizes data if the randomize button is pushed
            
n = app.NumberofAgentsEditField.Value;
L = app.SideLengthEditField.Value;

Agent_Positions = zeros(n,2);

for i = 1:n
    Agent_Positions(i,1) = randi(L-1);
    Agent_Positions(i,2) = randi(L-1);
end

app.UITable.Data(:,1:2) = Agent_Positions;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        app.CurrentIterationEditField.Value = app.FinalIterationEditField.Value+1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%This is the main body of the code. It executes Lloyd's algorithm when the start button is pressed. 
close('all')

%NOTE: SOME DOCUMENTATION MAY REFER TO THIS FUNCTION AS LLOYDSPLAY
%Get all of the input values that we'll need
sides = app.SideLengthEditField.Value;
Agent_Positions = app.UITable.Data(:,1:2);
Number_of_Agents = app.NumberofAgentsEditField.Value;
max_velocity = app.MaximumVelocityEditField.Value;
Density_Type = app.DensityTypeDropDown.Value;
Delay_Type = app.RandomDelayCheckBox.Value;
final_iteration = app.FinalIterationEditField.Value;
delay = app.DelayEditField.Value;
app.CurrentIterationEditField.Value = 1;
order_frequency = app.OrderFrequencyEditField.Value;
% order_queue is a sides*sides matrix where each location has the number of orders for that grid space
order_queue = zeros(sides,sides);
% wait_time is the # of iterations an agent requires to pick up a an order
% i.e. the length of time an agent is out of commision to grab an item
wait_time = app.OrderPickupTimeEditField.Value;

for i = 1:Number_of_Agents
    colours_energy(i,:) = rand(1,3); % Generate colours for energy example
end

%Assign numerical values to all of the decisions we make in the app
if strcmpi(Density_Type, 'Matrix')
    Density_Type = 1;
elseif strcmpi(Density_Type, 'Random')
    Density_Type = 2;
else
    Density_Type = 4;
end

History = [];
Order_History = [0 0 0];
for i = 1 : Number_of_Agents
    History(1:1,(2*i-1):2*i) = Agent_Positions(i,1:2);
end

if Density_Type == 1
    % If Density is from a pre-made matrix
    % Must ensure dimensions of initial matrix are equal to those of sides
    Density = load(app.DensityMatrixNameEditField.Value, '-mat');
    %convert struct to array (variable must be named D originally)
    Density = Density.D;
elseif Density_Type == 2
    % If density is random
    % Note: hard-coded value always gives random float between 0 and 2
    % for each location in grid
    Density = 2*rand(sides);
else
    % Density initialized as all ones
    Density = ones(sides);
end

% If a random delay is selected, the max delay is the value in the delay input field
if Delay_Type == 1
    delay_max = delay;
end

syms x y z
% yy, xx used in contour plot
[yy,xx] = meshgrid(linspace(0,sides,sides));

% Fix Agent_Positions
Agent_Positions = Fixing_Starting_Positions(Agent_Positions,sides);

distance_travelled = zeros(1);

% Total orders is intialized as density
total_orders = round(Density);
% Get initial total mass
Total_Mass = sum(sum(Density));

count = 1;
Orders_Cumulative = zeros(sides);
Mass_Cumulative = zeros(1,Number_of_Agents);
target_region_mass = Total_Mass/Number_of_Agents;

% Initialize Centroids to agent positions
Centroids = Agent_Positions;

% Initialize selected_orders to an empty cell array
selected_orders = cell(1,Number_of_Agents);

% agent_wait_time is a 1xN matrix where the ith number is the # of iterations
% an agent must continue to wait before doing anything
agent_wait_time = zeros(1, Number_of_Agents);

%----------------------------------------- Loop -----------------------------------------------------%

while (count <= final_iteration && app.CurrentIterationEditField.Value >0)

count = count + 1;

% Clear old plots
cla(app.UIAxes)
cla(app.UIAxes2)
cla(app.UIAxes3)
        
% Points are assigned based on Centroids of existing regions
% Then centroids are updated based on the newly assigned agent points
Agent_Points = assign_points(Centroids,sides,target_region_mass,Density);
Mass = mass_calc(Agent_Points, Density, Number_of_Agents);

%% UPDATE MASS FOR EXCEL
Mass_Cumulative = vertcat(Mass_Cumulative, (count-1)*Mass);
%% END UPDATE MASS FOR EXCEL

%% UPDATE UIAXES2
% Plotting and updating figure done here
independent = 1:(count-1); % for plotting the coverage figure
yyaxis(app.UIAxes2, 'left')
plot(app.UIAxes2,independent, distance_travelled, 'b');
hold(app.UIAxes2, 'on')
%% END UPDATE UIAXES2

% Calculate centroids
Centroids = centroid_finder(Agent_Points,Mass,Density,...
    Number_of_Agents,Agent_Positions);

% Before we move the agents, we need to sort the order_queue for all agents
[agent_orders, order_queue, selected_orders, new_wait_time] = sort_orders(Agent_Points, Agent_Positions, order_queue, selected_orders, wait_time);
agent_wait_time = agent_wait_time + new_wait_time;

%% UPDATE ORDER QUEUE TABLE
% Display order_queue by agent
last_row = 1;
app.UITable2.Data = [];
for agent_num = 1:Number_of_Agents
    % sz is # of distinct order locations for a given agent
    sz = size(agent_orders{1,agent_num},1);
    % For when I have to decipher this enigma line of code:
    % "The rows last_row to last_row + sz in the UITable equals the sz by 3 matrix
    % where the first 2 columns are the x,y point and the 3rd column is the agent num"
    if sz > 0
        app.UITable2.Data(last_row:last_row+sz-1,:) = horzcat(agent_orders{1,agent_num}, agent_num*ones(sz,1));
        last_row = last_row + sz;
    end
end
%% END UPDATE ORDER QUEUE TABLE

%% UPDATE ORDER QUEUE HISTORY
Order_History = vertcat(Order_History, [-1 -1 count-1], app.UITable2.Data);
%% END UPDATE ORDER QUEUE HISTORY

% After finding centroids and agent_orders, need to determine how
% agents will move. The following priority will be followed:
% If an agent is already getting an order: Continue getting it
% If there is an order in the agent's region that another agent isn't already retriveing
% If there are no orders, and the agent isn't currently retrieving an item,
% Then go to the home location (the centroid of the agent's region)
[Agent_Positions,distance_travelled,selected_orders] = move_agents(Agent_Positions,...
    Centroids,agent_orders,distance_travelled,max_velocity,selected_orders,agent_wait_time);

% Update agent wait times
agent_wait_time = update_wait_times(agent_wait_time);

%% UPDATE EXCEL VARIABLES
for k = 1 : Number_of_Agents % for saving to Excel
    Latest_History(1:1,(2*k-1):2*k) = Agent_Positions(k,1:2);
end
History = vertcat(History,Latest_History); % for saving to Excel
%% END UPDATE EXCEL VARIABLES


%% UPDATE UIAXES PLOTS
for i = 1:Number_of_Agents % Plot Agents and centroids
    % Begin plotting
    hold(app.UIAxes, 'on')
    % Scatter plot of Agent_Positions on UIAxes in blue filled dots if agent is active
    % And yellow dots if it is waiting
    if agent_wait_time(1,i) == 0
        scatter(app.UIAxes,Agent_Positions(i,1),...
            Agent_Positions(i,2),90,'blue','filled');
    else
        scatter(app.UIAxes,Agent_Positions(i,1),...
            Agent_Positions(i,2),90,'yellow','filled');
    end
    % Reset bounds/limits of plot, in case user zoomed in/out
    app.UIAxes.XLim = [0 sides];
    app.UIAxes.YLim = [0 sides];
    % Label each blue dot with an agent number
    label_agent_index = num2str(i);
    t.FontSize = 20;
    % Plot the label on UIAxes, 1/65 the side length of the arena away from agent's blue dot
    text(app.UIAxes, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
        label_agent_index);
    hold(app.UIAxes, 'on')
    % Plot centroids as a scatter plot on UIAxes too
    scatter(app.UIAxes,Centroids(i,1),Centroids(i,2),50,'red','filled');  
    hold(app.UIAxes, 'on')
    
    % Plot all the pending orders as green dots
    for j = 1:size(agent_orders{1,i},1)
        scatter(app.UIAxes,agent_orders{1,i}(j,1),...
            agent_orders{1,i}(j,2),50,'green','filled');
    end
end

% Update the table displayng agent positions
app.UITable.Data(:,1:2) = Agent_Positions;    
    
hold(app.UIAxes, 'on')
% Draw a Voronoi plot around the centroids
% NOTE: This Voronoi plot used to be centered around agent positions
% But makes more sense to use centroids in our implementation
voronoi(app.UIAxes,Centroids(:,1),Centroids(:,2));
hold(app.UIAxes, 'on')
% Draw the contours determined by the density on UIAxes
% Note: Should look into exactly how the contour plot works
% and figure out what the returned contour_plot is for
%[contour_plot, h] = contour(app.UIAxes,xx,yy,Density,4); % h is used for line width
%set(h,'linewidth',2);
%% END UPDATE UIAXES PLOTS

%% UPDATE UIAXES3
% Should try to update this to display assigned points better
hold(app.UIAxes3, 'on')
Plot_Assigned_Points(app, Agent_Points,Number_of_Agents,colours_energy,...
    Agent_Positions,sides);
hold(app.UIAxes3, 'on')
pause(0.1);
%% END UPDATE UIAXES3

%% UPDATE DENSITY AND MASS
if (count-1 <= final_iteration) || (final_iteration <= 0)
    cla(app.UIAxes);
    % If we have a random delay
    if Delay_Type == 1
        delay = randi([1 delay_max]);
    end
    [total_orders, order_queue,Density] = iteration_variation_fun(total_orders,order_queue,count-1,delay,...
        order_frequency,Density);
    %Density = total_orders / count;
    Total_Mass = sum(sum(Density));
    target_region_mass = Total_Mass/Number_of_Agents;
    % Each density in the .csv will be padded with a row of -1's
    Orders_Cumulative = vertcat(Orders_Cumulative, -1*ones(1,sides), total_orders);
end 
%% END UPDATE DENSITY AND MASS

% Visual update the current iteration
app.CurrentIterationEditField.Value = count;

end
% ------------------------------------ END LOOP ----------------------------------------

% Close plots and save data to .csv files in tabular format
close all;
% Delete old .csv files
delete agentData.csv;
delete distanceTravelled.csv;
delete ordersOverTime.csv;
delete orderQueueHistory.csv;
delete totalOrders.csv;
delete massHistory.csv;
% Create dynamic table header
agentData_table_headers = cell(1,Number_of_Agents*2);
for i = 1:Number_of_Agents
    agentData_table_headers{1,(i*2)-1} = strcat('Agent',num2str(i),'X');
    agentData_table_headers{1,i*2} = strcat('Agent',num2str(i),'Y');
end

% Create tables
History_table = array2table(History,'VariableNames',agentData_table_headers);
Dist_table = array2table(distance_travelled','VariableNames',{'DistanceTravelled'});
Order_table = array2table(Order_History,'VariableNames',{'X' 'Y' 'AgentNum'});
% Write tables
writetable(History_table,'agentData.csv');
writetable(Dist_table,'distanceTravelled.csv');
writetable(table(Orders_Cumulative), 'ordersOverTime.csv');
writetable(Order_table, 'orderQueueHistory.csv');
writetable(table(total_orders), 'totalOrders.csv');
writetable(table(Mass_Cumulative), 'massHistory.csv');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %this resets everything if the reset button is pushed
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.CurrentIterationEditField.Value = 0;
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = randi(L-1);
                Agent_Positions(i,2) = randi(L-1);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            app.UITable2.Data = [];
            
        end

        % Value changed function: NumberofAgentsEditField
        function NumberofAgentsEditFieldValueChanged(app, event)
            %This section re-randomizes the data if the number of agents is changed
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = randi(L-1);
                Agent_Positions(i,2) = randi(L-1);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            
        end

        % Value changed function: SideLengthEditField
        function SideLengthEditFieldValueChanged(app, event)
       %This section re-randomizes the data if the side length is changed
            
         n = app.NumberofAgentsEditField.Value;
         L = app.SideLengthEditField.Value;

        Agent_Positions = zeros(n,2);

        for i = 1:n
            Agent_Positions(i,1) = randi(L-1);
            Agent_Positions(i,2) = randi(L-1);
        end

        app.UITable.Data(:,1:2) = Agent_Positions;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arena')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Total Distance Travelled')
            xlabel(app.UIAxes2, 'Iteration Number')
            ylabel(app.UIAxes2, 'Distance')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Assigned Points')
            app.NumberofAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofAgentsEditFieldValueChanged, true);
            app.NumberofAgentsEditField.Value = 10;
            app.SideLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SideLengthEditFieldValueChanged, true);
            app.SideLengthEditField.Value = 5;
            app.MaximumVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumVelocityEditFieldLabel.Text = 'Maximum Velocity';
            app.MaximumVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumVelocityEditField.Value = 1;
            app.DensityTypeDropDownLabel = uilabel(app.UIFigure);
            app.DensityTypeDropDownLabel.Text = 'Density Type';
            app.DensityTypeDropDown = uidropdown(app.UIFigure);
            app.DensityTypeDropDown.Items = {'Matrix', 'Random', 'Ones'};
            app.DensityTypeDropDown.Value = 'Matrix';
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 5;
            app.FinalIterationEditFieldLabel = uilabel(app.UIFigure);
            app.FinalIterationEditFieldLabel.Text = 'Final Iteration';
            app.FinalIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalIterationEditField.Value = 50;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Agent #'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.RandomizePositionsButton = uibutton(app.UIFigure, 'push');
            app.RandomizePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizePositionsButtonPushed, true);
            app.RandomizePositionsButton.Text = 'Randomize Positions';
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditField.Value = 1;
            app.DensityMatrixNameEditFieldLabel = uilabel(app.UIFigure);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.UIFigure, 'text');
            app.OrderFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.OrderFrequencyEditFieldLabel.Text = 'Order Frequency';
            app.OrderFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderFrequencyEditField.Value = 1;
            app.RandomDelayCheckBox = uicheckbox(app.UIFigure);
            app.RandomDelayCheckBox.Text = 'Random Delay?';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Agent #'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false false];
            app.OrderPickupTimeEditFieldLabel = uilabel(app.UIFigure);
            app.OrderPickupTimeEditFieldLabel.Text = 'Order Pickup Time';
            app.OrderPickupTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderPickupTimeEditField.Limits = [0 Inf];
            app.OrderPickupTimeEditField.RoundFractionalValues = 'on';
            app.OrderPickupTimeEditField.Value = 1;
            app.OrderQueueLabel = uilabel(app.UIFigure);
            app.OrderQueueLabel.Text = 'Order Queue';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Lloyd_App_final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end